classdef lqr_calculate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KalkulasiLQRUIFigure  matlab.ui.Figure
        MatriksKGainLabel     matlab.ui.control.Label
        UITable_3             matlab.ui.control.Table
        MatriksRLabel         matlab.ui.control.Label
        UITable_2             matlab.ui.control.Table
        MatriksQLabel         matlab.ui.control.Label
        KalkulasiButton       matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Create a 6x6 matrix filled with zeros
            dataTableSatu = zeros(6);
            dataTableDua = zeros(3);
            dataTableTiga = zeros(3, 6);
            % Add data to the table UI Component
            app.UITable.Data = dataTableSatu;
            app.UITable_2.Data = dataTableDua;
            app.UITable_3.Data = dataTableTiga;
        end

        % Button pushed function: KalkulasiButton
        function KalkulasiButtonPushed(app, event)
            % inisialisasi matriks A dan B
            % A = [0 0 0 1 0 0; 0 0 0 0 1 0; 0 0 0 0 0 1; 0 0 0 0 0 0; 0 0 0 0 0 0; 0 12.485611600000000 12.485611600000000 0 0 0];
            A = [0 0 0 1 0 0; 0 0 0 0 1 0; 0 0 0 0 0 1; 0 0 0 0 0 0; 0 0 0 0 0 0; 0 -98.452800000000000 -98.452800000000000 0 0 0];
            %B = [0 0 0; 0 0 0; 0 0 0; 3.850000000000000 0 0; 0 5.120320000000000e+02 -5.120320000000000e+02; 0 -5.120320000000000e+02 1.480200000000000e+02];
            B = [0 0 0; 0 0 0; 0 0 0; 1.506591300000000e+02 0 0; 0 1.506591300000000e+02 -1.506591300000000e+02; 0 -1.506591300000000e+02 5.085535700000000e+02];
            % mengambil nilai dari tabel matriks Q dan R
            Q = get(app.UITable, 'Data');
            R = get(app.UITable_2, 'Data');
            % kalkulasi gain K LQR
            [K] = lqr(A,B,Q,R);
            % tampilkan hasil kalkulasi gain
            app.UITable_3.Data = K;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KalkulasiLQRUIFigure = uifigure('Visible', 'off');
            app.KalkulasiLQRUIFigure.Name = 'Kalkulasi LQR';
            app.UITable = uitable(app.KalkulasiLQRUIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable.ColumnEditable = true;
            app.UITable.Tooltip = {''};
            app.KalkulasiButton = uibutton(app.KalkulasiLQRUIFigure, 'push');
            app.KalkulasiButton.ButtonPushedFcn = createCallbackFcn(app, @KalkulasiButtonPushed, true);
            app.KalkulasiButton.Text = 'Kalkulasi';
            app.MatriksQLabel = uilabel(app.KalkulasiLQRUIFigure);
            app.MatriksQLabel.Text = 'Matriks Q';
            app.UITable_2 = uitable(app.KalkulasiLQRUIFigure);
            app.UITable_2.ColumnName = {'1'; '2'; '3'};
            app.UITable_2.RowName = {'1'; '2'; '3'};
            app.UITable_2.SelectionType = 'row';
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.Tooltip = {''};
            app.MatriksRLabel = uilabel(app.KalkulasiLQRUIFigure);
            app.MatriksRLabel.Text = 'Matriks R';
            app.UITable_3 = uitable(app.KalkulasiLQRUIFigure);
            app.UITable_3.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable_3.RowName = {'1'; '2'; '3'};
            app.UITable_3.SelectionType = 'row';
            app.MatriksKGainLabel = uilabel(app.KalkulasiLQRUIFigure);
            app.MatriksKGainLabel.Text = 'Matriks K (Gain)';
            % Show the figure after all components are created
            app.KalkulasiLQRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lqr_calculate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KalkulasiLQRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KalkulasiLQRUIFigure)
        end
    end
end